classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField_9       matlab.ui.control.NumericEditField
        EditField_9Label  matlab.ui.control.Label
        EditField_8       matlab.ui.control.NumericEditField
        EditField_8Label  matlab.ui.control.Label
        EditField_6       matlab.ui.control.NumericEditField
        EditField_6Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.NumericEditField
        EditField_5Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        EditField_7       matlab.ui.control.NumericEditField
        EditField_7Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        resetButton       matlab.ui.control.Button
        imageButton       matlab.ui.control.Button
        convolveButton    matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inimg % Description
        r % Description
        cmatrix % Description
        c % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: imageButton
        function imageButtonPushed(app, event)
            [file,path]=uigetfile("*.*");
            if isequal(file,0)
                figure(app.UIFigure);
                return;

            end
            figure(app.UIFigure);
            app.inimg=rgb2gray(imread(fullfile(path,file)));
            imshow(app.inimg,"Parent",app.UIAxes);
        end

        % Button pushed function: convolveButton
        function convolveButtonPushed(app, event)


            app.cmatrix=[app.EditField.Value app.EditField_4.Value  app.EditField_7.Value ;
                        app.EditField_2.Value  app.EditField_5.Value  app.EditField_8.Value ;
                        app.EditField_3.Value  app.EditField_6.Value  app.EditField_9.Value ];
            
      
            app.cmatrix=app.cmatrix /abs((app.EditField.Value+ app.EditField_4.Value + app.EditField_7.Value +app.EditField_2.Value + app.EditField_5.Value + app.EditField_8.Value +app.EditField_3.Value + app.EditField_6.Value  +app.EditField_9.Value ) )  ;

            
            app.inimg=conv2(app.inimg,double(app.cmatrix),"same");
            
            imshow(uint8(abs((app.inimg))),"Parent",app.UIAxes2);


        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            app.inimg="";
            app.r="";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'input')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'output')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.convolveButton = uibutton(app.UIFigure, 'push');
            app.convolveButton.ButtonPushedFcn = createCallbackFcn(app, @convolveButtonPushed, true);
            app.convolveButton.Text = 'convolve';
            app.imageButton = uibutton(app.UIFigure, 'push');
            app.imageButton.ButtonPushedFcn = createCallbackFcn(app, @imageButtonPushed, true);
            app.imageButton.Text = 'image';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end